classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        phuongphaptinhButton  matlab.ui.container.ButtonGroup
        ChiadoiButton         matlab.ui.control.RadioButton
        LapButton             matlab.ui.control.RadioButton
        TTButton              matlab.ui.control.RadioButton
        DrawButton            matlab.ui.control.Button
        myAxe                 matlab.ui.control.UIAxes
        Results               matlab.ui.control.Label
        fxEditFieldLabel      matlab.ui.control.Label
        fxEdit                matlab.ui.control.EditField
        fpEditFieldLabel      matlab.ui.control.Label
        fpEdit                matlab.ui.control.EditField
        aLabel                matlab.ui.control.Label
        aEdit                 matlab.ui.control.EditField
        saisoEditFieldLabel   matlab.ui.control.Label
        saisoEdit             matlab.ui.control.EditField
        bLabel                matlab.ui.control.Label
        bEdit                 matlab.ui.control.EditField
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: DrawButton
        function DrawButtonPushed(app, event)
           try
               fx = app.fxEdit.Value;
               fx = str2func(['@(x)' fx]);
               
               fp = app.fpEdit.Value;
               fp = str2func(['@(x)' fp]);
               
               a = app.aEdit.Value;
               a = str2num(a);
               
               b = app.bEdit.Value;
               b = str2num(b);
               
               saiso = app.saisoEdit.Value;
               saiso = str2num(saiso);
               
               if isempty(fx) || isempty(fp) || isempty(a) || isempty(b) || isempty(saiso)
                   throwError;
               end
               
               if app.ChiadoiButton.Value
                   [nghiem,solanlap] = chiadoi(fx,a,b,saiso);
               elseif app.LapButton.Value
                   [nghiem,solanlap] = lap(fp,a,b,saiso);
               elseif app.TTButton.Value
                   [nghiem,solanlap] = tieptuyen(fx,a,b,saiso);
               end
               
               app.Results.Text = ['Ket qua nghiem: ' num2str(nghiem) ', So lan lap: ' num2str(solanlap)];
               app.Results.FontColor = [0.00 0.00 0.00 ];
               
               Ox = linspace(a,b,10);
               Oy = fx(Ox);
               plot(app.myAxe,Ox,Oy);
               
           catch error
                switch(error.identifier)
                    case 'MATLAB:m_incomplete_statement'
                         msgbox('Vui long nhap day du ham fx fp','Error','error');
                    case 'MATLAB:minrhs'
 		         msgbox('Vui long nhap day du data input','Error','error');
                    otherwise
                         msgbox ({error.identifier,error.message},'Error','error');
                end  
           end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.phuongphaptinhButton = uibuttongroup(app.UIFigure);
            app.phuongphaptinhButton.Title = 'PHƯƠNG PHÁP TÍNH';
            app.ChiadoiButton = uiradiobutton(app.phuongphaptinhButton);
            app.ChiadoiButton.Text = 'Chia đôi';
            app.ChiadoiButton.Value = true;
            app.LapButton = uiradiobutton(app.phuongphaptinhButton);
            app.LapButton.Text = 'Lặp';
            app.TTButton = uiradiobutton(app.phuongphaptinhButton);
            app.TTButton.Text = 'Tiếp tuyến';
            app.DrawButton = uibutton(app.UIFigure, 'push');
            app.DrawButton.ButtonPushedFcn = createCallbackFcn(app, @DrawButtonPushed, true);
            app.DrawButton.Text = 'Draw';
            app.myAxe = uiaxes(app.UIFigure);
            title(app.myAxe, 'Đồ thị f(x)')
            xlabel(app.myAxe, 'X')
            ylabel(app.myAxe, 'Y')
            app.Results = uilabel(app.UIFigure);
            app.Results.Text = 'Results';
            app.fxEditFieldLabel = uilabel(app.UIFigure);
            app.fxEditFieldLabel.Text = 'fx';
            app.fxEdit = uieditfield(app.UIFigure, 'text');
            app.fpEditFieldLabel = uilabel(app.UIFigure);
            app.fpEditFieldLabel.Text = 'fp';
            app.fpEdit = uieditfield(app.UIFigure, 'text');
            app.aLabel = uilabel(app.UIFigure);
            app.aLabel.Text = 'a';
            app.aEdit = uieditfield(app.UIFigure, 'text');
            app.saisoEditFieldLabel = uilabel(app.UIFigure);
            app.saisoEditFieldLabel.Text = 'saiso';
            app.saisoEdit = uieditfield(app.UIFigure, 'text');
            app.bLabel = uilabel(app.UIFigure);
            app.bLabel.Text = 'b';
            app.bEdit = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end